classdef GCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatlabGCSUIFigure  matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        FlightData         matlab.ui.container.Tab
        UAV1Panel          matlab.ui.container.Panel
        degGaugeLabel      matlab.ui.control.Label
        AngleDeg_1         matlab.ui.control.SemicircularGauge
        kmhGaugeLabel      matlab.ui.control.Label
        SpeedKmh_1         matlab.ui.control.LinearGauge
        Lamp_1             matlab.ui.control.Lamp
        UAV2Panel          matlab.ui.container.Panel
        degGauge_2Label    matlab.ui.control.Label
        AngleDeg_2         matlab.ui.control.SemicircularGauge
        kmhGauge_2Label    matlab.ui.control.Label
        SpeedKmh_2         matlab.ui.control.LinearGauge
        Lamp_2             matlab.ui.control.Lamp
        UAV3Panel          matlab.ui.container.Panel
        degGauge_3Label    matlab.ui.control.Label
        AngleDeg_3         matlab.ui.control.SemicircularGauge
        kmhGauge_3Label    matlab.ui.control.Label
        SpeedKmh_3         matlab.ui.control.LinearGauge
        Lamp_3             matlab.ui.control.Lamp
        UAV4Panel          matlab.ui.container.Panel
        degGauge_4Label    matlab.ui.control.Label
        AngleDeg_4         matlab.ui.control.SemicircularGauge
        kmhGauge_4Label    matlab.ui.control.Label
        SpeedKmh_4         matlab.ui.control.LinearGauge
        Lamp_4             matlab.ui.control.Lamp
        UAV5Panel          matlab.ui.container.Panel
        degGauge_5Label    matlab.ui.control.Label
        AngleDeg_5         matlab.ui.control.SemicircularGauge
        kmhGauge_5Label    matlab.ui.control.Label
        SpeedKmh_5         matlab.ui.control.LinearGauge
        Lamp_5             matlab.ui.control.Lamp
        UserPanel          matlab.ui.container.Panel
        degGauge_6Label    matlab.ui.control.Label
        AngleDeg_0         matlab.ui.control.SemicircularGauge
        kmhGauge_6Label    matlab.ui.control.Label
        SpeedKmh_0         matlab.ui.control.LinearGauge
        Lamp_0             matlab.ui.control.Lamp
        DebugPanel         matlab.ui.container.Panel
        DataTextArea       matlab.ui.control.TextArea
        Switch             matlab.ui.control.ToggleSwitch
        ConfigTurning      matlab.ui.container.Tab
        TEditFieldLabel    matlab.ui.control.Label
        TEditField         matlab.ui.control.NumericEditField
        dtEditFieldLabel   matlab.ui.control.Label
        dtEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Simulator % use which simulator
        SimuFigure % Description
        SimuAxes
    end
    
    methods (Access = private)
        
        function SetSimuFigAx(app)
            if isempty(app.SimuFigure)||~isgraphics(app.SimuFigure)
                % create fig and axis
                app.SimuFigure = figure;
                app.SimuAxes = axes;
                % build relationship
                set(app.SimuFigure,'Children',app.SimuAxes)
                % set position near gcs
                p = app.MatlabGCSUIFigure.Position;
                p = p + [p(3),0,max(p(3:4))-p(3),max(p(3:4))-p(4)];
                set(app.SimuFigure,'Position',p);
            end
        end
        
        function setAngleSpeed(app,id)
            switch id
                case 1
                    AngleDeg = app.AngleDeg_1;
                    SpeedKmh = app.SpeedKmh_1;
                case 2
                    AngleDeg = app.AngleDeg_2;
                    SpeedKmh = app.SpeedKmh_2;
                case 3
                    AngleDeg = app.AngleDeg_3;
                    SpeedKmh = app.SpeedKmh_3;
                case 4
                    AngleDeg = app.AngleDeg_4;
                    SpeedKmh = app.SpeedKmh_4;
                case 5
                    AngleDeg = app.AngleDeg_5;
                    SpeedKmh = app.SpeedKmh_5;
                otherwise
                    AngleDeg = app.AngleDeg_0;
                    SpeedKmh = app.SpeedKmh_0;
            end
            AngleDeg.Value = -app.Simulator.model.model(1).q(3)/pi*180+90;
            if AngleDeg.Value>180
                AngleDeg.Value = app.AngleDeg_1.Value-360;
            end
            if AngleDeg.Value<-180
                AngleDeg.Value = app.AngleDeg_1.Value+360;
            end
            SpeedKmh.Value = app.Simulator.model.model(1).v*60;
        end
        
        function setLampColor(app,id,cl)
            switch id
                case 1
                    Lamp = app.Lamp_1;
                case 2
                    Lamp = app.Lamp_2;
                case 3
                    Lamp = app.Lamp_3;
                case 4
                    Lamp = app.Lamp_4;
                case 5
                    Lamp = app.Lamp_5;
                otherwise
                    Lamp = app.Lamp_0;
            end
            switch cl
                case 'r'
                    LampColor = [1 0 0];
                case 'g'
                    LampColor = [0 1 0];
                case 'b'
                    LampColor = [0 0 1];
                case 'c'
                    LampColor = [0 1 1];
                case 'm'
                    LampColor = [1 0 1];
                case 'y'
                    LampColor = [1 1 0];
                otherwise
                    LampColor = [1 1 1]/2;
            end
            Lamp.Color = LampColor;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            app.Simulator = gcs_simu();
            app.dtEditField.Value = app.Simulator.dt;
            app.TEditField.Value = app.Simulator.T;
            % set simulation
            app.SetSimuFigAx();
            for i=0:5
                app.setLampColor(i,'k');
            end
            % run simulation
            app.Simulator.run(app);
        end

        % Value changed function: Switch
        function StartDebugButtonPushed(app, event)
            if app.Switch.Value
                % set simulation
                app.SetSimuFigAx();
                % start loop
                while app.Simulator.t0<=app.TEditField.Value;
                    data = app.Simulator.show(app);
                    app.DataTextArea.Value = data;
                    for i=1:app.Simulator.model.n
                        app.setAngleSpeed(i);
                        app.setLampColor(i,app.Simulator.model.color(i));
                    end
                    app.Simulator.t0 = app.Simulator.t0+app.dtEditField.Value;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatlabGCSUIFigure = uifigure;
            app.MatlabGCSUIFigure.Name = 'Matlab GCS';
            app.TabGroup = uitabgroup(app.MatlabGCSUIFigure);
            app.FlightData = uitab(app.TabGroup);
            app.FlightData.Title = '飞行数据';
            app.UAV1Panel = uipanel(app.FlightData);
            app.UAV1Panel.Title = 'UAV1';
            app.degGaugeLabel = uilabel(app.UAV1Panel);
            app.degGaugeLabel.Text = '航向角（deg）';
            app.AngleDeg_1 = uigauge(app.UAV1Panel, 'semicircular');
            app.AngleDeg_1.Limits = [-180 180];
            app.kmhGaugeLabel = uilabel(app.UAV1Panel);
            app.kmhGaugeLabel.Text = '速度（km/h）';
            app.SpeedKmh_1 = uigauge(app.UAV1Panel, 'linear');
            app.Lamp_1 = uilamp(app.UAV1Panel);
            app.UAV2Panel = uipanel(app.FlightData);
            app.UAV2Panel.Title = 'UAV2';
            app.degGauge_2Label = uilabel(app.UAV2Panel);
            app.degGauge_2Label.Text = '航向角（deg）';
            app.AngleDeg_2 = uigauge(app.UAV2Panel, 'semicircular');
            app.AngleDeg_2.Limits = [-180 180];
            app.kmhGauge_2Label = uilabel(app.UAV2Panel);
            app.kmhGauge_2Label.Text = '速度（km/h）';
            app.SpeedKmh_2 = uigauge(app.UAV2Panel, 'linear');
            app.Lamp_2 = uilamp(app.UAV2Panel);
            app.UAV3Panel = uipanel(app.FlightData);
            app.UAV3Panel.Title = 'UAV3';
            app.degGauge_3Label = uilabel(app.UAV3Panel);
            app.degGauge_3Label.Text = '航向角（deg）';
            app.AngleDeg_3 = uigauge(app.UAV3Panel, 'semicircular');
            app.AngleDeg_3.Limits = [-180 180];
            app.kmhGauge_3Label = uilabel(app.UAV3Panel);
            app.kmhGauge_3Label.Text = '速度（km/h）';
            app.SpeedKmh_3 = uigauge(app.UAV3Panel, 'linear');
            app.Lamp_3 = uilamp(app.UAV3Panel);
            app.UAV4Panel = uipanel(app.FlightData);
            app.UAV4Panel.Title = 'UAV4';
            app.degGauge_4Label = uilabel(app.UAV4Panel);
            app.degGauge_4Label.Text = '航向角（deg）';
            app.AngleDeg_4 = uigauge(app.UAV4Panel, 'semicircular');
            app.AngleDeg_4.Limits = [-180 180];
            app.kmhGauge_4Label = uilabel(app.UAV4Panel);
            app.kmhGauge_4Label.Text = '速度（km/h）';
            app.SpeedKmh_4 = uigauge(app.UAV4Panel, 'linear');
            app.Lamp_4 = uilamp(app.UAV4Panel);
            app.UAV5Panel = uipanel(app.FlightData);
            app.UAV5Panel.Title = 'UAV5';
            app.degGauge_5Label = uilabel(app.UAV5Panel);
            app.degGauge_5Label.Text = '航向角（deg）';
            app.AngleDeg_5 = uigauge(app.UAV5Panel, 'semicircular');
            app.AngleDeg_5.Limits = [-180 180];
            app.kmhGauge_5Label = uilabel(app.UAV5Panel);
            app.kmhGauge_5Label.Text = '速度（km/h）';
            app.SpeedKmh_5 = uigauge(app.UAV5Panel, 'linear');
            app.Lamp_5 = uilamp(app.UAV5Panel);
            app.UserPanel = uipanel(app.FlightData);
            app.UserPanel.Title = '遥控';
            app.degGauge_6Label = uilabel(app.UserPanel);
            app.degGauge_6Label.Text = '航向角（deg）';
            app.AngleDeg_0 = uigauge(app.UserPanel, 'semicircular');
            app.AngleDeg_0.Limits = [-180 180];
            app.kmhGauge_6Label = uilabel(app.UserPanel);
            app.kmhGauge_6Label.Text = '速度（km/h）';
            app.SpeedKmh_0 = uigauge(app.UserPanel, 'linear');
            app.Lamp_0 = uilamp(app.UserPanel);
            app.DebugPanel = uipanel(app.FlightData);
            app.DebugPanel.Title = '信息中心';
            app.DataTextArea = uitextarea(app.DebugPanel);
            app.Switch = uiswitch(app.DebugPanel, 'toggle');
            app.Switch.Items = {'关', '开'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @StartDebugButtonPushed, true);
            app.Switch.Value = '关';
            app.ConfigTurning = uitab(app.TabGroup);
            app.ConfigTurning.Title = '参数设置';
            app.TEditFieldLabel = uilabel(app.ConfigTurning);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.ConfigTurning, 'numeric');
            app.TEditField.Value = 2;
            app.dtEditFieldLabel = uilabel(app.ConfigTurning);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.ConfigTurning, 'numeric');
            app.dtEditField.Value = 0.02;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GCS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatlabGCSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatlabGCSUIFigure)
        end
    end
end